classdef appSimgestion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimIMABlockLevelSuggestionConfigurationUIFigure  matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        NoofSuggestionsDropDownLabel  matlab.ui.control.Label
        NoofSuggestionsDropDown       matlab.ui.control.DropDown
        CustomRepositoriesPanel       matlab.ui.container.Panel
        ButtonAdd                     matlab.ui.control.Button
        ButtonRemove1                 matlab.ui.control.Button
        ButtonRemove2                 matlab.ui.control.Button
        ButtonRemove3                 matlab.ui.control.Button
        ButtonRemove4                 matlab.ui.control.Button
        ButtonRemove5                 matlab.ui.control.Button
        LabelRemove1                  matlab.ui.control.Label
        LabelRemove2                  matlab.ui.control.Label
        LabelRemove3                  matlab.ui.control.Label
        LabelRemove4                  matlab.ui.control.Label
        LabelRemove5                  matlab.ui.control.Label
        DefaultRepositoriesPanel      matlab.ui.container.Panel
        AutomotiveCheckBox            matlab.ui.control.CheckBox
        AvionicsCheckBox              matlab.ui.control.CheckBox
        ElectronicsCheckBox           matlab.ui.control.CheckBox
        EnergyCheckBox                matlab.ui.control.CheckBox
        RoboticsCheckBox              matlab.ui.control.CheckBox
        MiscellaneousCheckBox         matlab.ui.control.CheckBox
        Image                         matlab.ui.control.Image
        ApplyChangesButton            matlab.ui.control.Button
        CancelChangesButton           matlab.ui.control.Button
        ResetconfigurationButton      matlab.ui.control.Button
        UpdatingmodelsLabel           matlab.ui.control.Label
        PerformancePanel              matlab.ui.container.Panel
        Slider                        matlab.ui.control.Slider
        SpeedLabel                    matlab.ui.control.Label
        AccuracyLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)  % todo: change to private 
        
        % It is not a good idea to apply 'all' changes made by the user
        % from the UI immediately for 2 reasons: 
        % - updating prediction models takes long to complete 
        % - the user may want to undo changes 
        % so we use the attribute 'newState' to store all changes made by 
        % the user from the UI such as checking/unchecking a standard repo, 
        % adding custom repo, and changing the number of suggestions. 
        % These changes are not applied until the user hits 'Apply Changes'
        % button. 
        % In case the user hits 'Cancel changes', 'newState' is restored
        % back to the value stored in shared-var, thus all changes made in 
        % UI are undone 
        newState  % AppState 


    end
    
    methods (Access = private)
           
%         function handleRemoveButtonPushed(app, buttonNumber)
%             app.newState.("cstRepoPath" + buttonNumber) = ""; 
%             app.newState.("abspathsOfMdlSlxInCstRepo" + buttonNumber) = string.empty; 
%             app.newState.("nMdlSlxCstRepoPath" + buttonNumber) = 0; 
%             app.rearrangeCustomRepos(); 
%             app.refreshCustomRepoPanel(); 
%         end
        
        function handleRemoveButtonPushed(app, buttonNumber)
            app.newState.("cstRepoPath" + buttonNumber) = ""; 
            app.rearrangeCustomRepos(); 
            app.newState = app.newState.setModelFilePathsAndCounts();  
            app.refreshCustomRepoPanel(); 
        end


        function rearrangeCustomRepos(app)
            % this method makes sure that app.newState.cstRepoPathX is set (to some non-empty value)
            % before app.newState.cstRepoPathY, where X < Y 
            
            for i = 1 : 5
                for j = i+1 : 5
                    prop1 = "cstRepoPath" + i; 
                    prop2 = "cstRepoPath" + j; 
                    
                    % this is a cool way to access properties by their
                    % string representation. 
                    if app.newState.(prop1) == "" && app.newState.(prop2) ~= ""
                        app.newState.(prop1) = app.newState.(prop2); 
                        app.newState.(prop2) = ""; 
                    end
                end
            end
        end
        
        function refreshUI(app)
            % Refreshes the UI based on the shared var 'simvma_appState' 
            state = getSharedVar('simvma_appState'); 
            app.NoofSuggestionsDropDown.Value = char(string(state.simgestionNSuggsMax));
            app.Slider.Value = state.simgestionAccuracyLevel; 
            
            app.AutomotiveCheckBox.Value = state.defRepo1Checked; 
            app.AvionicsCheckBox.Value = state.defRepo2Checked;
            app.ElectronicsCheckBox.Value = state.defRepo3Checked;
            app.EnergyCheckBox.Value = state.defRepo4Checked;
            app.RoboticsCheckBox.Value = state.defRepo5Checked;
            app.MiscellaneousCheckBox.Value = state.defRepo6Checked;
            
            
            % set labels in custom repo panel             
            app.LabelRemove1.Text = trimLabel(state.cstRepoPath1 + " (" + state.nMdlSlxCstRepoPath1 + " models)", 64);
            app.LabelRemove2.Text = trimLabel(state.cstRepoPath2 + " (" + state.nMdlSlxCstRepoPath2 + " models)", 64);
            app.LabelRemove3.Text = trimLabel(state.cstRepoPath3 + " (" + state.nMdlSlxCstRepoPath3 + " models)", 64);
            app.LabelRemove4.Text = trimLabel(state.cstRepoPath4 + " (" + state.nMdlSlxCstRepoPath4 + " models)", 64);
            app.LabelRemove5.Text = trimLabel(state.cstRepoPath5 + " (" + state.nMdlSlxCstRepoPath5 + " models)", 64);
      
            % set visibility in custom repo panel 
            for i = 1 : 5
                if state.("cstRepoPath" + i) == ""
                    app.("ButtonRemove"+i).Visible = false; 
                    app.("LabelRemove"+i).Visible = false; 
                else 
                    app.("ButtonRemove"+i).Visible = true; 
                    app.("LabelRemove"+i).Visible = true;
                    % disable 'Add' button if there are already 5 custom repos 
                    if i == 5
                        app.ButtonAdd.Enable = false; 
                    end
                end
            end
            
            app.Image.Visible = false; 
            app.UpdatingmodelsLabel.Visible = false; 
        end
        
        function refreshCustomRepoPanel(app)
            % Refreshes the custom repo panel only based on app.newState
            % set labels in custom repo panel 
            app.LabelRemove1.Text = trimLabel(app.newState.cstRepoPath1 + " (" + app.newState.nMdlSlxCstRepoPath1 + " models)", 64);
            app.LabelRemove2.Text = trimLabel(app.newState.cstRepoPath2 + " (" + app.newState.nMdlSlxCstRepoPath2 + " models)", 64);
            app.LabelRemove3.Text = trimLabel(app.newState.cstRepoPath3 + " (" + app.newState.nMdlSlxCstRepoPath3 + " models)", 64);
            app.LabelRemove4.Text = trimLabel(app.newState.cstRepoPath4 + " (" + app.newState.nMdlSlxCstRepoPath4 + " models)", 64);
            app.LabelRemove5.Text = trimLabel(app.newState.cstRepoPath5 + " (" + app.newState.nMdlSlxCstRepoPath5 + " models)", 64);
      
            % set visibility in custom repo panel 
            app.ButtonAdd.Enable = true;  % will be disabled if needed (by the following loop) 
            for i = 1 : 5
                if app.newState.("cstRepoPath" + i) == ""
                    app.("ButtonRemove"+i).Visible = false; 
                    app.("LabelRemove"+i).Visible = false; 
                else 
                    app.("ButtonRemove"+i).Visible = true; 
                    app.("LabelRemove"+i).Visible = true;
                    % disable 'Add' button if there are already 5 custom repos 
                    if i == 5
                        app.ButtonAdd.Enable = false; 
                    end
                end
            end
        end
        
        
        function enterBusyState(app)
            % disable all User-interaction options 
            app.NoofSuggestionsDropDown.Enable = false; 
            app.AutomotiveCheckBox.Enable = false; 
            app.AvionicsCheckBox.Enable = false; 
            app.ButtonAdd.Enable = false; 
            app.ButtonRemove1.Enable = false; 
            app.ButtonRemove2.Enable = false; 
            app.ButtonRemove3.Enable = false; 
            app.ButtonRemove4.Enable = false; 
            app.ButtonRemove5.Enable = false; 
            app.Image.Visible = true; 
            app.UpdatingmodelsLabel.Visible = true; 
            app.ApplyChangesButton.Enable = false; 
            app.CancelChangesButton.Enable = false; 
            app.ResetconfigurationButton.Enable = false; 
        end
        
        function exitBusyState(app)
            % enable all User-interaction options
            app.NoofSuggestionsDropDown.Enable = true; 
            app.AutomotiveCheckBox.Enable = true; 
            app.AvionicsCheckBox.Enable = true; 
            app.ButtonAdd.Enable = true; 
            app.ButtonRemove1.Enable = true; 
            app.ButtonRemove2.Enable = true; 
            app.ButtonRemove3.Enable = true; 
            app.ButtonRemove4.Enable = true; 
            app.ButtonRemove5.Enable = true; 
            app.Image.Visible = false; 
            app.UpdatingmodelsLabel.Visible = false; 
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
            app.ResetconfigurationButton.Enable = true;  
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', "var_appSimgestion", app);
            
            % initially newState is the same as state last saved. 
            app.newState = getSharedVar('simvma_appState');
            app.Image.ImageSource = getSimvmaPath + '/images/busy1.gif'; 
            
            
            app.AutomotiveCheckBox.Text = "Automotive (" + app.newState.nMdlSlxDefRepoPath1 + " models)";
            app.AvionicsCheckBox.Text = "Avionics (" + app.newState.nMdlSlxDefRepoPath2 + " models)";
            app.ElectronicsCheckBox.Text = "Electronics (" + app.newState.nMdlSlxDefRepoPath3 + " models)";
            app.EnergyCheckBox.Text = "Energy (" + app.newState.nMdlSlxDefRepoPath4 + " models)";
            app.RoboticsCheckBox.Text = "Robotics (" + app.newState.nMdlSlxDefRepoPath5 + " models)";
            app.MiscellaneousCheckBox.Text = "Miscellaneous (" + app.newState.nMdlSlxDefRepoPath6 + " models)";
            
            app.AutomotiveCheckBox.Tooltip = app.newState.defRepoPath1; 
            app.AvionicsCheckBox.Tooltip = app.newState.defRepoPath2;
            app.ElectronicsCheckBox.Tooltip = app.newState.defRepoPath3;
            app.EnergyCheckBox.Tooltip = app.newState.defRepoPath4;
            app.RoboticsCheckBox.Tooltip = app.newState.defRepoPath5;
            app.MiscellaneousCheckBox.Tooltip = app.newState.defRepoPath6;
            
                        
            app.refreshUI(); 
        end

        % Value changed function: NoofSuggestionsDropDown
        function NoofSuggestionsDropDownValueChanged(app, event)
            value = app.NoofSuggestionsDropDown.Value;
            app.newState.simgestionNSuggsMax = str2double(value); 
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Value changed function: AutomotiveCheckBox
        function AutomotiveCheckBoxValueChanged(app, event)
            app.newState.defRepo1Checked = app.AutomotiveCheckBox.Value;   
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Value changed function: AvionicsCheckBox
        function AvionicsCheckBoxValueChanged(app, event)
            app.newState.defRepo2Checked = app.AvionicsCheckBox.Value;      
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            % we set simvma_appState before invoking
            % updatePredModels(), so that the new state can be 
            % accessed from inside updatePredModels() using getSharedVar() 
            prevState = getSharedVar('simvma_appState'); 
            setSharedVar('simvma_appState', app.newState);
            
            if detectChangesInRepos(prevState, app.newState, true)  % available at functions/ (this is not app's method) 
                app.enterBusyState(); 
                updatePredModels(true);  % available at functions/ (this is not app's method) 
                app.exitBusyState();   
            end
            app.refreshUI();  
        end

        % Button pushed function: CancelChangesButton
        function CancelChangesButtonPushed(app, event)
            app.refreshUI(); 
            % restore app.newState to previous persisted state. 
            app.newState = getSharedVar('simvma_appState'); 
        end

        % Button pushed function: ResetconfigurationButton
        function ResetconfigurationButtonPushed(app, event)
            prevState = getSharedVar('simvma_appState');   
            app.newState = AppState(); % state with default values as defined in the class's constructor 
            setSharedVar('simvma_appState', app.newState); 
            
            if detectChangesInRepos(prevState, app.newState, true)  % available at functions/ (this is not app's method) 
                app.enterBusyState(); 
                updatePredModels(true);  % available at functions/ (this is not app's method) 
                app.exitBusyState(); 
            end
            app.refreshUI();   
        end

        % Button pushed function: ButtonAdd
        function ButtonAddPushed(app, event)
            customRepoPath = string(uigetdir());  % uigetdir() returns absolute path as char vector 
            if customRepoPath ~= "0"  % "0" is returned if user hits 'Cancel' button from the popped-out window 
            
            if customRepoPath.startsWith(getSimvmaPath())
                msgbox("Custom Repo path must be outside SimVMA.");
                return; 
            end
                % avoid duplicate 
                if any(strcmp([app.newState.cstRepoPath1, app.newState.cstRepoPath2, app.newState.cstRepoPath3, app.newState.cstRepoPath4, app.newState.cstRepoPath5], customRepoPath))
                    msgbox("This path is already chosen.");
                    return; 
                end
            
                % assign to the first empty custom repopath (of the 5 total)
                for i = 1 : 5 
                    if app.newState.("cstRepoPath" + i) == ""
                        app.newState.("cstRepoPath" + i) = customRepoPath; 
                        app.newState = app.newState.setModelFilePathsAndCounts(); 
                        break; 
                    end
                end
                app.refreshCustomRepoPanel(); 
            end
        end

        % Button pushed function: ButtonRemove1
        function ButtonRemove1Pushed(app, event)
            app.handleRemoveButtonPushed(1);
        end

        % Button pushed function: ButtonRemove2
        function ButtonRemove2Pushed(app, event)
            app.handleRemoveButtonPushed(2);
        end

        % Button pushed function: ButtonRemove3
        function ButtonRemove3Pushed(app, event)
            app.handleRemoveButtonPushed(3);
        end

        % Button pushed function: ButtonRemove4
        function ButtonRemove4Pushed(app, event)
            app.handleRemoveButtonPushed(4);
        end

        % Button pushed function: ButtonRemove5
        function ButtonRemove5Pushed(app, event)
            app.handleRemoveButtonPushed(5);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            value = round(value); 
            app.Slider.Value = value;  % for immediate reflection in UI  
            app.newState.simgestionAccuracyLevel = value; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimIMABlockLevelSuggestionConfigurationUIFigure = uifigure('Visible', 'off');
            app.SimIMABlockLevelSuggestionConfigurationUIFigure.Name = 'SimIMA : Block-Level Suggestion Configuration';
            app.Panel = uipanel(app.SimIMABlockLevelSuggestionConfigurationUIFigure);
            app.NoofSuggestionsDropDownLabel = uilabel(app.Panel);
            app.NoofSuggestionsDropDownLabel.Text = 'No. of Suggestions';
            app.NoofSuggestionsDropDown = uidropdown(app.Panel);
            app.NoofSuggestionsDropDown.Items = {'3', '4', '5', '6', '7', '8'};
            app.NoofSuggestionsDropDown.ValueChangedFcn = createCallbackFcn(app, @NoofSuggestionsDropDownValueChanged, true);
            app.NoofSuggestionsDropDown.Value = '3';
            app.CustomRepositoriesPanel = uipanel(app.Panel);
            app.CustomRepositoriesPanel.Title = 'Custom Repositories';
            app.ButtonAdd = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPushed, true);
            app.ButtonAdd.Tooltip = {'Add new repository path to train the Block Prediction Models with.'};
            app.ButtonAdd.Text = '+';
            app.ButtonRemove1 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove1.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove1Pushed, true);
            app.ButtonRemove1.Text = '-';
            app.ButtonRemove2 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove2.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove2Pushed, true);
            app.ButtonRemove2.Text = '-';
            app.ButtonRemove3 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove3.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove3Pushed, true);
            app.ButtonRemove3.Text = '-';
            app.ButtonRemove4 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove4.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove4Pushed, true);
            app.ButtonRemove4.Text = '-';
            app.ButtonRemove5 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove5.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove5Pushed, true);
            app.ButtonRemove5.Text = '-';
            app.LabelRemove1 = uilabel(app.CustomRepositoriesPanel);
            app.LabelRemove2 = uilabel(app.CustomRepositoriesPanel);
            app.LabelRemove3 = uilabel(app.CustomRepositoriesPanel);
            app.LabelRemove4 = uilabel(app.CustomRepositoriesPanel);
            app.LabelRemove5 = uilabel(app.CustomRepositoriesPanel);
            app.DefaultRepositoriesPanel = uipanel(app.Panel);
            app.DefaultRepositoriesPanel.Title = 'Default Repositories';
            app.AutomotiveCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.AutomotiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomotiveCheckBoxValueChanged, true);
            app.AutomotiveCheckBox.Text = 'Automotive';
            app.AutomotiveCheckBox.Value = true;
            app.AvionicsCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.AvionicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvionicsCheckBoxValueChanged, true);
            app.AvionicsCheckBox.Text = 'Avionics';
            app.AvionicsCheckBox.Value = true;
            app.ElectronicsCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.ElectronicsCheckBox.Text = 'Electronics';
            app.ElectronicsCheckBox.Value = true;
            app.EnergyCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.EnergyCheckBox.Text = 'Energy';
            app.EnergyCheckBox.Value = true;
            app.RoboticsCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.RoboticsCheckBox.Text = 'Robotics';
            app.RoboticsCheckBox.Value = true;
            app.MiscellaneousCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.MiscellaneousCheckBox.Text = 'Miscellaneous';
            app.MiscellaneousCheckBox.Value = true;
            app.Image = uiimage(app.Panel);
            app.ApplyChangesButton = uibutton(app.Panel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Tooltip = {'Apply the changes you have made.  Changes won''t persist unless applied.'};
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.CancelChangesButton = uibutton(app.Panel, 'push');
            app.CancelChangesButton.ButtonPushedFcn = createCallbackFcn(app, @CancelChangesButtonPushed, true);
            app.CancelChangesButton.Tooltip = {'Cancel the changes you have made.'};
            app.CancelChangesButton.Text = 'Cancel Changes';
            app.ResetconfigurationButton = uibutton(app.Panel, 'push');
            app.ResetconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetconfigurationButtonPushed, true);
            app.ResetconfigurationButton.Tooltip = {'This will remove all custom repos, select both standard repos, choose fast and less relevant (favors speed over accuracy) suggestions, and set no.of suggestions to 3.'};
            app.ResetconfigurationButton.Text = 'Reset configuration';
            app.UpdatingmodelsLabel = uilabel(app.Panel);
            app.UpdatingmodelsLabel.Text = 'Updating models...';
            app.PerformancePanel = uipanel(app.Panel);
            app.PerformancePanel.Title = 'Performance';
            app.Slider = uislider(app.PerformancePanel);
            app.Slider.Limits = [1 3];
            app.Slider.MajorTicks = [1 2 3];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [1 2 3];
            app.Slider.Value = 3;
            app.SpeedLabel = uilabel(app.PerformancePanel);
            app.SpeedLabel.Text = 'Speed';
            app.AccuracyLabel = uilabel(app.PerformancePanel);
            app.AccuracyLabel.Text = 'Accuracy';
            % Show the figure after all components are created
            app.SimIMABlockLevelSuggestionConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSimgestion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimIMABlockLevelSuggestionConfigurationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimIMABlockLevelSuggestionConfigurationUIFigure)
        end
    end
end