classdef appSimxample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimXampleUIFigure           matlab.ui.Figure
        Panel_2                     matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        CustomRepositoriesPanel     matlab.ui.container.Panel
        ButtonAdd                   matlab.ui.control.Button
        ButtonRemove1               matlab.ui.control.Button
        ButtonRemove2               matlab.ui.control.Button
        ButtonRemove3               matlab.ui.control.Button
        LabelRemove2                matlab.ui.control.Label
        LabelRemove3                matlab.ui.control.Label
        LabelRemove1                matlab.ui.control.Label
        ButtonRemove4               matlab.ui.control.Button
        LabelRemove4                matlab.ui.control.Label
        ButtonRemove5               matlab.ui.control.Button
        LabelRemove5                matlab.ui.control.Label
        SuggestionSimilarityButtonGroup  matlab.ui.container.ButtonGroup
        RadioButton10               matlab.ui.control.RadioButton
        RadioButton20               matlab.ui.control.RadioButton
        RadioButton30               matlab.ui.control.RadioButton
        DefaultRepositoriesPanel    matlab.ui.container.Panel
        AutomotiveCheckBox          matlab.ui.control.CheckBox
        AvionicsCheckBox            matlab.ui.control.CheckBox
        ElectronicsCheckBox         matlab.ui.control.CheckBox
        EnergyCheckBox              matlab.ui.control.CheckBox
        RoboticsCheckBox            matlab.ui.control.CheckBox
        MiscellaneousCheckBox       matlab.ui.control.CheckBox
        ApplyChangesButton          matlab.ui.control.Button
        CancelChangesButton         matlab.ui.control.Button
        ResetconfigurationButton    matlab.ui.control.Button
        Panel_3                     matlab.ui.container.Panel
        Suggestion1MatchscorePanel  matlab.ui.container.Panel
        ChooseButton1               matlab.ui.control.Button
        Image1                      matlab.ui.control.Image
        Suggestion2MatchscorePanel  matlab.ui.container.Panel
        ChooseButton2               matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        PreviousButton              matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)  % change to private (was set to public for debugging) 
        
        % It is not a good idea to apply 'all' changes made by the user
        % from the UI immediately for 2 reasons: 
        % - updating prediction models takes long to complete 
        % - the user may want to undo changes 
        % so we use the attribute 'newState' to store all changes made by 
        % the user from the UI such as checking/unchecking a standard repo, 
        % and adding custom repo
        % These changes are not applied until the user hits 'Apply Changes'
        % button. 
        % In case the user hits 'Cancel changes', 'newState' is restored
        % back to the value stored in shared-var, thus all changes made in 
        % UI are undone 
        newState  % AppState
        

        nSuggActual;      % actual number of suggestions; same as #clones returned by getSortedClones; <= N_SUGG_MAX          
        sugg1Index = 0 ;  % which index of app.suggestions the first suggestion (displayed) corresponds to 
        
        % to be set at app's start-up 
        SIMVMA_PATH 
        MODEL_PATH
        
        clones     
        suggestions 
        sud  % system under development as returned by gcs. eg: "mymodel/mysubsystem"
    end
    
    methods (Access = private)
        
        function status = setModelPathAndReturnStatus(app)
            % set app.MODEL_PATH and return status (true/false) 
            % IMPORTANT: cannot use any functions inside scripts folder yet
            
            try 
                app.MODEL_PATH = get_param(bdroot, 'FileName'); 
                status = true; 
            catch ME  % MException object 
                status = false; 
            end
        end
        
        
        function loadImageBusyGif(app) 
            app.Image1.ImageSource = app.SIMVMA_PATH + "/images/busy.gif"; 
            app.Image2.ImageSource = app.SIMVMA_PATH + "/images/busy.gif";
        end
       
        
        function status = setSudAndUpdateSimoneInputFile(app)
            app.sud = string(gcs); 
            createMdlFileForSimone(app.MODEL_PATH, app.sud, app.SIMVMA_PATH); 
            status = true; 
        end
        
        
        function loadImageSuggs(app)
            % loads suggestion images (or empty.png) and corresponding 'choose' buttons based on app.suggestions and app.sugg1Index  
            
            if length(app.suggestions) == 0
                app.Image1.ImageSource = app.SIMVMA_PATH + "/images/empty.png";
                app.Image2.ImageSource = app.SIMVMA_PATH + "/images/empty.png";
                
                app.Suggestion1MatchscorePanel.Title = "Suggestion 1 (Not found)";
                app.Suggestion2MatchscorePanel.Title = "Suggestion 2 (Not Found)";
                
                app.ChooseButton1.Enable = false;
                app.ChooseButton2.Enable = false;
                
            elseif app.sugg1Index < length(app.suggestions)
                i = app.sugg1Index; 
                app.Image1.ImageSource = app.suggestions{i}.imgFilepath; 
                app.Image2.ImageSource = app.suggestions{i+1}.imgFilepath; 
                
                app.Suggestion1MatchscorePanel.Title = "Suggestion " + i + " of " + length(app.suggestions) + " ( Match score: " + app.suggestions{i}.similarity + "% )";
                app.Suggestion2MatchscorePanel.Title = "Suggestion " + (i+1) + " of " + length(app.suggestions) + " ( Match score: " + app.suggestions{i+1}.similarity + "% )";                    
            
                app.ChooseButton1.Enable = true;
                app.ChooseButton2.Enable = true;
                
            else 
                i = app.sugg1Index; 
                app.Image1.ImageSource = app.suggestions{i}.imgFilepath; 
                app.Image2.ImageSource = app.SIMVMA_PATH + "/images/empty.png";
                
                app.Suggestion1MatchscorePanel.Title = "Suggestion " + i + " of " + length(app.suggestions) + " ( Match score: " + app.suggestions{i}.similarity + "% )";
                app.Suggestion2MatchscorePanel.Title = "Suggestion 2 (Not Found)";
            
                app.ChooseButton1.Enable = true;
                app.ChooseButton2.Enable = false;
            end           
        end
        
        
        function repoPaths = getRepoPaths(app, appState)
            % returns relevant repoPaths as absolute folderpaths (for simone) based on appState             
            
            repoPaths = string.empty; 
           
            % add selected default repos
            for i = 1 : 6
                if appState.("defRepo"+ i + "Checked")
                    repoPath = getSimvmaPath() + "/" + appState.("defRepoPath" + i);  % relative (to simvma) to absolute conversion 
                    repoPaths = [repoPaths repoPath];  
                end
            end
            
            % add custom repos if any 
            for i = 1 : 5
                repoPath = appState.("cstRepoPath" + i);
                if repoPath ~= ""
                    repoPaths = [repoPaths repoPath]; 
                end
            end
        end
      
        
        function updateSuggestions(app) 
            % updates app.suggestions based on app.newState 
            % This method does not check if updating suggestions is
            % required or not. It will always update the suggestions. Any
            % check (to avoid unnecessary updating of suggestions) must be
            % made by the caller.
            
            save_system(app.MODEL_PATH); 
            app.setSudAndUpdateSimoneInputFile(); 
            
            dispImp("MUD : " + app.MODEL_PATH); 
            dispTitle("SUD : " + app.sud); 
            
            app.suggestions = {}; % reset
            repoPaths = app.getRepoPaths(app.newState);
            
            app.clones = getSortedClones(app.SIMVMA_PATH, repoPaths, app.newState.simxampleNSuggsMax, true, app.sud, ...
                app.newState.simxampleSimoneDiffLimit, app.newState.simxampleSimoneMinSize, app.newState.simxampleSimoneMaxSize, app.newState.simxampleSimoneRename);      
            nClones = length(app.clones);
            disp(newline + string(nClones) + " Clones selected (after filtration).");
            
            if nClones
                for i = 1:nClones 
                    suggestion = getSuggestionFromClone(app.clones{i}, i, app.SIMVMA_PATH); 
                    app.suggestions{length(app.suggestions) + 1} = suggestion; 
                end 
            end 
            
            assignin('base', 'suggestions', app.suggestions); 
            disp(string(nClones) + " Suggestions created.");
            dispSuggestions(app.suggestions, "ALL SUGGESTIONS ( Total : " + length(app.suggestions) + " )"); 
            if length(app.suggestions) > 0
                app.sugg1Index = 1; 
            else 
                app.sugg1Index = 0; 
            end            
        end
        
        
        function handleRemoveButtonPushed(app, buttonNumber)
            app.newState.("cstRepoPath" + buttonNumber) = ""; 
            app.rearrangeCustomRepos(); 
            app.newState = app.newState.setModelFilePathsAndCounts();  
            app.refreshCustomRepoPanel(); 
        end
        
        
        function enableDisableNextPrev(app)
            % Enables/Disables next and prev buttons based on
            % app.suggestions and app.sugg1Index 
            if app.sugg1Index > 1
                app.PreviousButton.Enable = true; 
            else 
                app.PreviousButton.Enable = false; 
            end
            
            if app.sugg1Index + 1 < length(app.suggestions) 
                app.NextButton.Enable = true; 
            else 
                app.NextButton.Enable = false; 
            end
        end
        
        
        function forceRefreshUI(app)
            drawnow; 
        end
  
        
        function refreshUI(app)
            % Refreshes the UI based on the shared var 'simvma_appState' and app.suggestions  
            
            % 1. enter busy state 
            % 2. make necessary changes in UI 
            % 3. exit busy state
            
            app.enterBusyState(); 
            
            state = getSharedVar('simvma_appState'); 
            
            app.AutomotiveCheckBox.Value = state.defRepo1Checked; 
            app.AvionicsCheckBox.Value = state.defRepo2Checked;
            app.ElectronicsCheckBox.Value = state.defRepo3Checked;
            app.EnergyCheckBox.Value = state.defRepo4Checked;
            app.RoboticsCheckBox.Value = state.defRepo5Checked;
            app.MiscellaneousCheckBox.Value = state.defRepo6Checked;
            
            
            % set labels in custom repo panel             
            app.LabelRemove1.Text = trimLabel(state.cstRepoPath1 + " (" + state.nMdlCstRepoPath1 + " models)", 17);
            app.LabelRemove2.Text = trimLabel(state.cstRepoPath2 + " (" + state.nMdlCstRepoPath2 + " models)", 17);
            app.LabelRemove3.Text = trimLabel(state.cstRepoPath3 + " (" + state.nMdlCstRepoPath3 + " models)", 17);
            app.LabelRemove4.Text = trimLabel(state.cstRepoPath4 + " (" + state.nMdlCstRepoPath4 + " models)", 17);
            app.LabelRemove5.Text = trimLabel(state.cstRepoPath5 + " (" + state.nMdlCstRepoPath5 + " models)", 17);
      
            % set visibility in custom repo panel 
            for i = 1 : 5
                if state.("cstRepoPath" + i) == ""
                    app.("ButtonRemove"+i).Visible = false; 
                    app.("LabelRemove"+i).Visible = false; 
                else 
                    app.("ButtonRemove"+i).Visible = true; 
                    app.("LabelRemove"+i).Visible = true;
                    % disable 'Add' button if there are already 5 custom repos 
                    if i == 5
                        app.ButtonAdd.Enable = false; 
                    end
                end
            end

            % simone similarity threshold 
            app.RadioButton10.Value = 0; 
            app.RadioButton20.Value = 0;
            app.RadioButton30.Value = 0; 
            app.("RadioButton" + state.simxampleSimoneDiffLimit).Value = 1; 
            
            % update suggestion images and the corresponding 'choose' buttons 
            app.loadImageSuggs(); 
            app.enableDisableNextPrev(); 
            
            
            app.exitBusyState(); 
            
        end
        
        
        function refreshCustomRepoPanel(app)
            % Refreshes the custom repo panel only based on app.newState
            % set labels in custom repo panel 
            app.LabelRemove1.Text = trimLabel(app.newState.cstRepoPath1 + " (" + app.newState.nMdlCstRepoPath1 + " models)", 17);
            app.LabelRemove2.Text = trimLabel(app.newState.cstRepoPath2 + " (" + app.newState.nMdlCstRepoPath2 + " models)", 17);
            app.LabelRemove3.Text = trimLabel(app.newState.cstRepoPath3 + " (" + app.newState.nMdlCstRepoPath3 + " models)", 17);
            app.LabelRemove4.Text = trimLabel(app.newState.cstRepoPath4 + " (" + app.newState.nMdlCstRepoPath4 + " models)", 17);
            app.LabelRemove5.Text = trimLabel(app.newState.cstRepoPath5 + " (" + app.newState.nMdlCstRepoPath5 + " models)", 17);
      
            % set visibility in custom repo panel 
            app.ButtonAdd.Enable = true;  % will be disabled if needed (by the following loop) 
            for i = 1 : 5
                if app.newState.("cstRepoPath" + i) == ""
                    app.("ButtonRemove"+i).Visible = false; 
                    app.("LabelRemove"+i).Visible = false; 
                else 
                    app.("ButtonRemove"+i).Visible = true; 
                    app.("LabelRemove"+i).Visible = true;
                    % disable 'Add' button if there are already 5 custom repos 
                    if i == 5
                        app.ButtonAdd.Enable = false; 
                    end
                end
            end
        end
        
        
    end
    
    methods (Access = public)
        
        function enterBusyState(app)
            app.ApplyChangesButton.Enable = false; 
            app.CancelChangesButton.Enable = false; 
            app.ResetconfigurationButton.Enable = false; 
            
            app.AutomotiveCheckBox.Enable = false; 
            app.AvionicsCheckBox.Enable = false; 
            app.ElectronicsCheckBox.Enable = false; 
            app.EnergyCheckBox.Enable = false; 
            app.RoboticsCheckBox.Enable = false; 
            app.MiscellaneousCheckBox.Enable = false; 
            
            app.ButtonAdd.Enable = false; 
            app.ButtonRemove1.Enable = false; 
            app.ButtonRemove2.Enable = false; 
            app.ButtonRemove3.Enable = false; 
            app.ButtonRemove4.Enable = false; 
            app.ButtonRemove5.Enable = false; 
            
            app.RadioButton10.Enable = false; 
            app.RadioButton20.Enable = false; 
            app.RadioButton30.Enable = false; 

            app.ChooseButton1.Enable = false; 
            app.ChooseButton2.Enable = false; 
            
            app.NextButton.Enable = false; 
            app.PreviousButton.Enable = false; 
            
            app.loadImageBusyGif();
            
            app.forceRefreshUI(); 
        end
        
        
        function exitBusyState(app)
            % Exits the busy state 
            
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
            app.ResetconfigurationButton.Enable = true; 
            
            app.AutomotiveCheckBox.Enable = true; 
            app.AvionicsCheckBox.Enable = true; 
            app.ElectronicsCheckBox.Enable = true; 
            app.EnergyCheckBox.Enable = true; 
            app.RoboticsCheckBox.Enable = true; 
            app.MiscellaneousCheckBox.Enable = true; 
            
            app.ButtonAdd.Enable = true; 
            app.ButtonRemove1.Enable = true; 
            app.ButtonRemove2.Enable = true; 
            app.ButtonRemove3.Enable = true; 
            app.ButtonRemove4.Enable = true; 
            app.ButtonRemove5.Enable = true; 
            
            app.RadioButton10.Enable = true; 
            app.RadioButton20.Enable = true; 
            app.RadioButton30.Enable = true; 

            app.ChooseButton1.Enable = true; 
            app.ChooseButton2.Enable = true;
                        
            app.forceRefreshUI(); 
        end
        
        
        function rearrangeCustomRepos(app)
            % this method makes sure that app.newState.cstRepoPathX is set (to some non-empty value)
            % before app.newState.cstRepoPathY, where X < Y 
            
            for i = 1 : 3
                for j = i+1 : 3
                    prop1 = "cstRepoPath" + i; 
                    prop2 = "cstRepoPath" + j; 
                    
                    % this is a cool way to access properties by their
                    % string representation. 
                    if app.newState.(prop1) == "" && app.newState.(prop2) ~= ""
                        app.newState.(prop1) = app.newState.(prop2); 
                        app.newState.(prop2) = ""; 
                    end
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', "var_appSimxample", app);
%             movegui(app.SimXampleUIFigure, "southeast"); % position SimVMA window at bottom right
%             movegui(app.SimXampleUIFigure, "southwest"); % position SimVMA window at bottom right
            movegui(app.SimXampleUIFigure, "center"); 
            app.SIMVMA_PATH = getSimvmaPath();  
            app.enterBusyState(); % can not be called before setting app.SIMVMA_PATH 
            modelPathStatus = app.setModelPathAndReturnStatus; 
            
            if ~modelPathStatus
                disp("*** ERROR: Model path not set"); 
                disp("    Make sure the simulink model is loaded.");
                closereq;
            end 
            
            % initially newState is the same as state last saved. 
            app.newState = getSharedVar('simvma_appState');
            
            app.AutomotiveCheckBox.Text = "Automotive  (" + app.newState.nMdlDefRepoPath1 + " models)";
            app.AvionicsCheckBox.Text = "Avionics  (" + app.newState.nMdlDefRepoPath2 + " models)";
            app.ElectronicsCheckBox.Text = "Electronics  (" + app.newState.nMdlDefRepoPath3 + " models)";
            app.EnergyCheckBox.Text = "Enegry  (" + app.newState.nMdlDefRepoPath4 + " models)";
            app.RoboticsCheckBox.Text = "Robotics  (" + app.newState.nMdlDefRepoPath5 + " models)";
            app.MiscellaneousCheckBox.Text = "Miscellaneous  (" + app.newState.nMdlDefRepoPath6 + " models)";
            
            app.AutomotiveCheckBox.Tooltip = app.newState.defRepoPath1; 
            app.AvionicsCheckBox.Tooltip = app.newState.defRepoPath2;
            app.ElectronicsCheckBox.Tooltip = app.newState.defRepoPath3;
            app.EnergyCheckBox.Tooltip = app.newState.defRepoPath4;
            app.RoboticsCheckBox.Tooltip = app.newState.defRepoPath5;
            app.MiscellaneousCheckBox.Tooltip = app.newState.defRepoPath6;
            
            app.updateSuggestions(); 
            app.refreshUI(); 
        end

        % Button pushed function: ChooseButton1
        function ChooseButton1Pushed(app, event)
            updateMUDWithSuggestion(app.sud, app.suggestions{app.sugg1Index}, app.MODEL_PATH, app.SIMVMA_PATH); 
            % close the simxample window after inserting the suggestion into MUD
            closereq; 
        end

        % Button pushed function: ChooseButton2
        function ChooseButton2Pushed(app, event)
            updateMUDWithSuggestion(app.sud, app.suggestions{app.sugg1Index + 1}, app.MODEL_PATH, app.SIMVMA_PATH); 
        end

        % Button pushed function: ButtonAdd
        function ButtonAddPushed(app, event)
            customRepoPath = string(uigetdir());  % uigetdir() returns absolute path as char vector 
            if customRepoPath ~= "0"  % "0" is returned if user hits 'Cancel' button from the popped-out window 
            
            if customRepoPath.startsWith(getSimvmaPath())
                msgbox("Custom Repo path must be outside SimVMA.");
                return; 
            end
                
                % avoid duplicate 
                if any(strcmp([app.newState.cstRepoPath1, app.newState.cstRepoPath2, app.newState.cstRepoPath3, app.newState.cstRepoPath4, app.newState.cstRepoPath5], customRepoPath))
                    msgbox("This path is already chosen.");
                    return; 
                end
            
                % assign to the first empty custom repopath (of the 5 total)
                for i = 1 : 5 
                    if app.newState.("cstRepoPath" + i) == ""
                        app.newState.("cstRepoPath" + i) = customRepoPath; 
                        app.newState = app.newState.setModelFilePathsAndCounts(); 
                        break; 
                    end
                end
                app.refreshCustomRepoPanel(); 
            end
        end

        % Close request function: SimXampleUIFigure
        function SimXampleUIFigureCloseRequest(app, event)
            delete(app); 
        end

        % Button pushed function: ButtonRemove1
        function ButtonRemove1Pushed(app, event)
            app.handleRemoveButtonPushed(1); 
        end

        % Button pushed function: ButtonRemove2
        function ButtonRemove2Pushed(app, event)
            app.handleRemoveButtonPushed(2); 
        end

        % Button pushed function: ButtonRemove3
        function ButtonRemove3Pushed(app, event)
            app.handleRemoveButtonPushed(3); 
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.sugg1Index = app.sugg1Index - 1; 
            app.loadImageSuggs(); 
            app.enableDisableNextPrev(); 
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.sugg1Index = app.sugg1Index + 1; 
            app.loadImageSuggs();  
            app.enableDisableNextPrev(); 
        end

        % Selection changed function: 
        % SuggestionSimilarityButtonGroup
        function SuggestionSimilarityButtonGroupSelectionChanged(app, event)
            selectedButtonText = string(app.SuggestionSimilarityButtonGroup.SelectedObject.Text);
            if contains(selectedButtonText, "90")
                app.newState.simxampleSimoneDiffLimit = 10; 
            elseif contains(selectedButtonText, "80")
                app.newState.simxampleSimoneDiffLimit = 20; 
            else 
                app.newState.simxampleSimoneDiffLimit = 30;
            end    
        end

        % Value changed function: AutomotiveCheckBox
        function AutomotiveCheckBoxValueChanged(app, event)
            app.newState.defRepo1Checked = app.AutomotiveCheckBox.Value;   
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Value changed function: AvionicsCheckBox
        function AvionicsCheckBoxValueChanged(app, event)
            app.newState.defRepo2Checked = app.AvionicsCheckBox.Value;   
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Value changed function: ElectronicsCheckBox
        function ElectronicsCheckBoxValueChanged(app, event)
            app.newState.defRepo3Checked = app.ElectronicsCheckBox.Value;   
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Value changed function: EnergyCheckBox
        function EnergyCheckBoxValueChanged(app, event)
            app.newState.defRepo4Checked = app.EnergyCheckBox.Value;   
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Value changed function: RoboticsCheckBox
        function RoboticsCheckBoxValueChanged(app, event)
            app.newState.defRepo5Checked = app.RoboticsCheckBox.Value;   
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Value changed function: MiscellaneousCheckBox
        function MiscellaneousCheckBoxValueChanged(app, event)
            app.newState.defRepo6Checked = app.MiscellaneousCheckBox.Value;   
            app.ApplyChangesButton.Enable = true; 
            app.CancelChangesButton.Enable = true; 
        end

        % Button pushed function: ResetconfigurationButton
        function ResetconfigurationButtonPushed(app, event)
            prevState = getSharedVar('simvma_appState');   
            app.newState = AppState(); % state with default values as defined in the class's constructor 
            setSharedVar('simvma_appState', app.newState); 
            
            if detectChangesInRepos(prevState, app.newState, true)  % available at functions/ (this is not app's method) 
                app.enterBusyState(); 
                updatePredModels(true);  % available at functions/ (this is not app's method) 
                app.updateSuggestions(); 
                app.exitBusyState();
                
            % we need to update suggestion not only on changes in repos but
            % also when the user changes the similarity threshold
            elseif prevState.simxampleSimoneDiffLimit ~= app.newState.simxampleSimoneDiffLimit
                app.enterBusyState();
                app.updateSuggestions();
                app.exitBusyState();
            end
            app.refreshUI();
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            % we set simvma_appState before invoking
            % updatePredModels(), so that the new state can be 
            % accessed from inside updatePredModels() using getSharedVar() 
            prevState = getSharedVar('simvma_appState'); 
            setSharedVar('simvma_appState', app.newState);
            
            if detectChangesInRepos(prevState, app.newState, true)  % available at functions/ (this is not app's method) 
                app.enterBusyState(); 
                updatePredModels(true);  % available at functions/ (this is not app's method) 
                app.updateSuggestions(); 
                app.exitBusyState(); 
                
            % we need to update suggestion not only on changes in repos but
            % also when the user changes the similarity threshold
            elseif prevState.simxampleSimoneDiffLimit ~= app.newState.simxampleSimoneDiffLimit
                app.enterBusyState();
                app.updateSuggestions();
                app.exitBusyState(); 
            end
            app.refreshUI();
        end

        % Button pushed function: CancelChangesButton
        function CancelChangesButtonPushed(app, event)
            app.refreshUI(); 
            % restore app.newState to previous persisted state. 
            app.newState = getSharedVar('simvma_appState');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimXampleUIFigure = uifigure('Visible', 'off');
            app.SimXampleUIFigure.Name = 'SimXample';
            app.SimXampleUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimXampleUIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.SimXampleUIFigure);
            app.Panel = uipanel(app.Panel_2);
            app.CustomRepositoriesPanel = uipanel(app.Panel);
            app.CustomRepositoriesPanel.Title = 'Custom Repositories';
            app.ButtonAdd = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPushed, true);
            app.ButtonAdd.Tooltip = {'Add New Custom Repository'};
            app.ButtonAdd.Text = '+';
            app.ButtonRemove1 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove1.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove1Pushed, true);
            app.ButtonRemove1.Text = '-';
            app.ButtonRemove2 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove2.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove2Pushed, true);
            app.ButtonRemove2.Text = '-';
            app.ButtonRemove3 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove3.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemove3Pushed, true);
            app.ButtonRemove3.Text = '-';
            app.LabelRemove2 = uilabel(app.CustomRepositoriesPanel);
            app.LabelRemove3 = uilabel(app.CustomRepositoriesPanel);
            app.LabelRemove1 = uilabel(app.CustomRepositoriesPanel);
            app.ButtonRemove4 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove4.Text = '-';
            app.LabelRemove4 = uilabel(app.CustomRepositoriesPanel);
            app.ButtonRemove5 = uibutton(app.CustomRepositoriesPanel, 'push');
            app.ButtonRemove5.Text = '-';
            app.LabelRemove5 = uilabel(app.CustomRepositoriesPanel);
            app.SuggestionSimilarityButtonGroup = uibuttongroup(app.Panel);
            app.SuggestionSimilarityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SuggestionSimilarityButtonGroupSelectionChanged, true);
            app.SuggestionSimilarityButtonGroup.Tooltip = {'Maximum allowable difference between the system being developed and the suggestion'};
            app.SuggestionSimilarityButtonGroup.Title = 'Suggestion Similarity';
            app.RadioButton10 = uiradiobutton(app.SuggestionSimilarityButtonGroup);
            app.RadioButton10.Text = 'Very similar               > 90%';
            app.RadioButton10.Value = true;
            app.RadioButton20 = uiradiobutton(app.SuggestionSimilarityButtonGroup);
            app.RadioButton20.Text = 'Somewhat similar     > 80%';
            app.RadioButton30 = uiradiobutton(app.SuggestionSimilarityButtonGroup);
            app.RadioButton30.Text = 'Less similar              > 70%';
            app.DefaultRepositoriesPanel = uipanel(app.Panel);
            app.DefaultRepositoriesPanel.Title = 'Default Repositories';
            app.AutomotiveCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.AutomotiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomotiveCheckBoxValueChanged, true);
            app.AutomotiveCheckBox.Text = 'Automotive';
            app.AutomotiveCheckBox.Value = true;
            app.AvionicsCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.AvionicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvionicsCheckBoxValueChanged, true);
            app.AvionicsCheckBox.Text = 'Avionics';
            app.AvionicsCheckBox.Value = true;
            app.ElectronicsCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.ElectronicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ElectronicsCheckBoxValueChanged, true);
            app.ElectronicsCheckBox.Text = 'Electronics';
            app.ElectronicsCheckBox.Value = true;
            app.EnergyCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.EnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnergyCheckBoxValueChanged, true);
            app.EnergyCheckBox.Text = 'Energy';
            app.EnergyCheckBox.Value = true;
            app.RoboticsCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.RoboticsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RoboticsCheckBoxValueChanged, true);
            app.RoboticsCheckBox.Text = 'Robotics';
            app.RoboticsCheckBox.Value = true;
            app.MiscellaneousCheckBox = uicheckbox(app.DefaultRepositoriesPanel);
            app.MiscellaneousCheckBox.ValueChangedFcn = createCallbackFcn(app, @MiscellaneousCheckBoxValueChanged, true);
            app.MiscellaneousCheckBox.Text = 'Miscellaneous';
            app.MiscellaneousCheckBox.Value = true;
            app.ApplyChangesButton = uibutton(app.Panel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Tooltip = {'Apply the changes you have made.  Changes won''t persist unless applied.'};
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.CancelChangesButton = uibutton(app.Panel, 'push');
            app.CancelChangesButton.ButtonPushedFcn = createCallbackFcn(app, @CancelChangesButtonPushed, true);
            app.CancelChangesButton.Tooltip = {'Cancel the changes you have made.'};
            app.CancelChangesButton.Text = 'Cancel Changes';
            app.ResetconfigurationButton = uibutton(app.Panel, 'push');
            app.ResetconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetconfigurationButtonPushed, true);
            app.ResetconfigurationButton.Tooltip = {'This will choose ''Miscellaneous'' repo from default repos, remove all custom repos, and produce suggestions > 70% similar to the system under development.'};
            app.ResetconfigurationButton.Text = 'Reset configuration';
            app.Panel_3 = uipanel(app.Panel_2);
            app.Suggestion1MatchscorePanel = uipanel(app.Panel_3);
            app.Suggestion1MatchscorePanel.Title = 'Suggestion 1 ( Match score: )';
            app.ChooseButton1 = uibutton(app.Suggestion1MatchscorePanel, 'push');
            app.ChooseButton1.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton1Pushed, true);
            app.ChooseButton1.Tooltip = {''};
            app.ChooseButton1.Text = 'Choose';
            app.Image1 = uiimage(app.Suggestion1MatchscorePanel);
            app.Suggestion2MatchscorePanel = uipanel(app.Panel_3);
            app.Suggestion2MatchscorePanel.Title = 'Suggestion 2 ( Match score: )';
            app.ChooseButton2 = uibutton(app.Suggestion2MatchscorePanel, 'push');
            app.ChooseButton2.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton2Pushed, true);
            app.ChooseButton2.Tooltip = {''};
            app.ChooseButton2.Text = 'Choose';
            app.Image2 = uiimage(app.Suggestion2MatchscorePanel);
            app.PreviousButton = uibutton(app.Panel_3, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Tooltip = {''};
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.Panel_3, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {''};
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.SimXampleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appSimxample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimXampleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimXampleUIFigure)
        end
    end
end